classdef HealthMonitor_v5Copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AvgField                   matlab.ui.control.EditField
        HeartaverLabel             matlab.ui.control.Label
        MinField                   matlab.ui.control.EditField
        HeartmaxLabel              matlab.ui.control.Label
        MaxField                   matlab.ui.control.EditField
        HeartmaxLabel_2            matlab.ui.control.Label
        GraphTabGroup              matlab.ui.container.TabGroup
        BioSignalsTab              matlab.ui.container.Tab
        TimewindowEditField        matlab.ui.control.NumericEditField
        TimewindowsEditFieldLabel  matlab.ui.control.Label
        plot1                      matlab.ui.control.UIAxes
        SettingTabGroup            matlab.ui.container.TabGroup
        SerialCommSettingTab       matlab.ui.container.Tab
        SerialStartButton          matlab.ui.control.Button
        SerialSaveButton           matlab.ui.control.Button
        SerialStopButton           matlab.ui.control.Button
        SerialConnectionLamp       matlab.ui.control.Lamp
        SerialConnectButton        matlab.ui.control.Button
        BaudRateDropDown           matlab.ui.control.DropDown
        BaudRateDropDownLabel      matlab.ui.control.Label
        PortNumberDropDown         matlab.ui.control.DropDown
        PortNumberDropDownLabel    matlab.ui.control.Label
        HealthStatusPanel          matlab.ui.container.Panel
        HeartRateEditField         matlab.ui.control.EditField
        HeartRateEditFieldLabel    matlab.ui.control.Label
        HealthMonitorLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        Data      % Data
        PlotFlag
        SerialFlag
        FSRFlag
        TimestampsFIFOBuffer  % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer1        % Data FIFO buffer used for live plot of latest "N" seconds of acquired data

        RpeakIndexBuffer
        LivePlotLine1         % Handle to line plot of acquired data

        ECG
        usb_nrf

        cnt
        b_h_b
        a_h_b
        b_l_b
        a_l_b

        dX_h_1
        dY_h_1
        dX_l_30
        dY_l_30

        Window1
        Window2
        Window3

        len1
        len2
        len3

        pre_value

        PEAK_BUFF
        PEAK_LOC
        PEAK_INTV

        F_PEAK_LOC
        F_PEAK_INTV

        MEAN_HR
        AudioPlayerHandle % 노래 재생 객체 관리를 위한 속성
        % 💡 추가: 15초 재생 제어를 위한 속성
        % ======================================
        AlarmPlayStartTime % 알람 재생 시작 시간 기록 (datenum)
        AlarmDuration = 15; % 알람 재생 지속 시간 (초)
        HeartRateHistory
    end

    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%% Serial Communication %%%%%%%%%%%%%%%%%%%%%%%%
        function usb_nrf = SerialCommRequest(app, PN_v, BR_v, check_stat)
            if check_stat ==1
                usb_nrf = serialport(PN_v,BR_v);
                app.usb_nrf = usb_nrf;

                check_buffer = zeros(1, 22);

                while(1)
                    check_buffer(1:21) = check_buffer(2:22); % 시프트 시킴
                    check_buffer(22)   = read(usb_nrf, 1, "uint8"); % 1비트씩 데이터를 얽어옴 

                    % 첫 값이 0xA5이고 22번째 값이 0x5A이면, 가운데 20개의 데이터를 가져와 계산
                    if check_buffer(1) == 165 && check_buffer(22) == 90 % 165: 0xA5, 90: 0x5A
                        outval1 = zeros(1, 10);
                        cnt_t = 1;

                        tmp = check_buffer(2:21);

                        for kk=1:2:20
                            t_high = tmp(kk);
                            t_low = tmp(kk+1);
                            t1 = int16(bitshift(int16(t_high), 8) + t_low);

                            outval1(cnt_t) = t1;
                            cnt_t = cnt_t + 1;
                        end
                        app.ECG = [app.ECG; outval1(:)];
                        updateplot(app);
                    end
                end

            elseif check_stat == 0
                clear app.usb_nrf;
                clear usb_nrf;
            end

        end

        %%%%%%%%%%%%%%%%%%%%%%%%% Update plot %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updateplot(app)
            if app.SerialFlag == 1 % serial data case
                ECG = app.ECG;
                fs  = 100;
            else
                return;
            end

            st_idx = app.Data.st_idx;   % 1  11  21 ...
            end_idx = app.Data.end_idx; % 10 20  30 ...

            time_x = [st_idx:end_idx];
            timestamps = time_x(:)/fs;

            buffersize = fs * app.TimewindowEditField.Value;
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, timestamps);


            app.FSRFlag = 1;
            filt_Data = CAL_HR_MERGED(app, ECG);
            app.DataFIFOBuffer1 = storeDataInFIFO(app, app.DataFIFOBuffer1, buffersize, filt_Data);

            set(app.LivePlotLine1, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer1(:)); app.plot1.YLimMode = 'auto';

            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.plot1, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)]);
            end

            app.Data.st_idx = st_idx + fs/10;
            app.Data.end_idx = end_idx + fs/10;

            app.ECG = [];

            if (app.PlotFlag == 1)
                drawnow;
            elseif (app.PlotFlag == 0) | (app.Data.end_idx > length(ECG))

                return;
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%% Data buffering %%%%%%%%%%%%%%%%%%%%%%%%%%%
        function data = storeDataInFIFO(~, data, buffersize, datablock)

            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end

            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%% HR extraction %%%%%%%%%%%%%%%%%%%%%%%%%%%
        function f_value = CAL_HR_MERGED(app, data)
            f_value = zeros(app.Data.fs/10, 1);

            for kk=1:1:length(data)
                app.cnt = app.cnt + 1;

                [SMBCG, f_value(kk), app.dX_h_1, app.dY_h_1, app.dX_l_30, app.dY_l_30, app.Window1, app.Window2, app.Window3, app.pre_value] =  ...
                    ECR_ECG_PREPROCESSING(data(kk), app.Window1, app.Window2, app.Window3, app.b_h_b, app.a_h_b, app.b_l_b, app.a_l_b, ...
                    app.dX_h_1, app.dY_h_1, app.dX_l_30, app.dY_l_30, app.len1, app.len2, app.len3, app.pre_value);

                % 피크 검출
                app.PEAK_BUFF(1)   = app.PEAK_BUFF(2);
                app.PEAK_BUFF(2)   = app.PEAK_BUFF(3);
                app.PEAK_BUFF(3)   = SMBCG;

                % 피크가 검출되면,
                if app.cnt > 2*app.Data.fs
                    if app.PEAK_BUFF(3) < app.PEAK_BUFF(2) && app.PEAK_BUFF(2) > app.PEAK_BUFF(1)
                        app.PEAK_LOC        =  [app.PEAK_LOC (app.cnt-1)];
                        app.PEAK_INTV       =  [app.PEAK_INTV (app.PEAK_LOC(end)-app.PEAK_LOC(end-1))/app.Data.fs];

                        tmp = (app.PEAK_LOC(end)-app.PEAK_LOC(end-1))/app.Data.fs;
                        tmp2 =    60./(mean(tmp));

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                        
                        % MCU로 어떤 값을 보냄
                        % tmp3 = cast(tmp2, 'uint8');
                        % write(app.usb_nrf, tmp3, 'uint8');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    else
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % write(app.usb_nrf, 0, 'uint8');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                end

                if app.cnt >= app.Data.fs*10 && mod(app.cnt, app.Data.fs*2) == 0
                    S_idx     =    find(app.PEAK_LOC >= (app.cnt - app.Data.fs*3 + 1) & app.PEAK_LOC <= app.cnt);
                    tmp_loc   =    app.PEAK_LOC(S_idx);
                    tmp_Intv  =    app.PEAK_INTV(S_idx);

                    idx                =   find(tmp_Intv >= 2 | tmp_Intv <= 0.5);
                    tmp_loc(idx)       =   [];
                    tmp_Intv(idx)      =   [];

                    %%%%%%%%%%%%%%%%%%%%%% rpeak_i buffer %%%%%%%%%%%%%%%%%%%%
                    app.RpeakIndexBuffer = storeDataInFIFO(app, app.RpeakIndexBuffer, 60, tmp_loc'); % 1 min buffer

                    [MEAN_HR]      =    CW_CPP_CAL_MEAN_HR_SINGLE(tmp_Intv, 2);

                    if MEAN_HR ~= 0
                        tmp = double(MEAN_HR); 
                        tmp2 = sprintf('%.1f' , tmp); 
                        app.HeartRateEditField.Value = tmp2;

                        % ==================================================
                        % 💡 [수정됨] 실시간 평균, 최소, 최대 계산 및 표시
                        % ==================================================
                        % 1. 현재 심박수를 기록에 추가
                        app.HeartRateHistory = [app.HeartRateHistory, tmp];
                        
                        % 2. 통계 계산
                        currMin = min(app.HeartRateHistory);
                        currMax = max(app.HeartRateHistory);
                        currAvg = mean(app.HeartRateHistory);
                        
                        % 3. GUI에 표시 (소수점 1자리까지)
                        % 주의: 사용자분의 UI 변수명과 라벨이 서로 섞여 있는 것으로 보입니다.
                        % 논리적으로 맞는 변수에 값을 넣습니다. (화면과 다르면 변수명을 바꾸세요)
                        app.MinField.Value = sprintf('%.1f', currMin); % 최소값 표시
                        app.MaxField.Value = sprintf('%.1f', currMax); % 최대값 표시
                        app.AvgField.Value = sprintf('%.1f', currAvg); % 평균값 표시
                        % ==================================================

                        % ==================================================
                        % 💡 1. CSV 파일 저장 로직 추가
                        % ==================================================
                        timestamp = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
                        T = table(timestamp, tmp, 'VariableNames', {'Timestamp', 'HeartRate_BPM'});
                        
                        csvFileName = 'HeartRate_Log.csv'; % 파일 이름
                        
                        % 파일이 없으면 헤더 포함하여 생성, 있으면 데이터만 추가 (헤더는 제외)
                        if ~exist(csvFileName, 'file')
                            writetable(T, csvFileName, 'WriteMode', 'overwrite', 'Delimiter', ',');
                        else
                            writetable(T, csvFileName, 'WriteMode', 'append', 'Delimiter', ',', 'WriteVariableNames', false); 
                        end
                        % ==================================================


                        % 1. 노래 파일 경로 설정
                        % ⚠️ 경로를 실제 파일 경로로 수정하세요!
                        songFile = 'C:\Users\taejeong\Downloads\GGang.mp3'; 
                        % MP3 대신 WAV 파일 사용을 강력히 권장합니다. (GGang.mp3 -> GGang.wav로 가정)

                        if tmp >= 90
                            % A. 심박수가 90 bpm 이상일 때: 재생 시작
                            
                            % 플레이어 핸들이 준비되었고, 현재 재생 중이 아니라면 시작합니다.
                            if exist(songFile, 'file') && (isempty(app.AudioPlayerHandle) || ~isvalid(app.AudioPlayerHandle) || ~isplaying(app.AudioPlayerHandle))
                                
                                try
                                    [y, Fs] = audioread(songFile);
                                    
                                    % 기존 플레이어가 유효하더라도 새 파일로 재생을 위해 객체를 새로 생성
                                    app.AudioPlayerHandle = audioplayer(y, Fs);
                                    
                                    play(app.AudioPlayerHandle);
                                    
                                    % 재생 시작 시간 기록
                                    app.AlarmPlayStartTime = now; % 현재 시간을 datenum 형식으로 기록
                                    
                                catch ME
                                    disp(['Error loading or playing audio: ', ME.message]);
                                end
                            elseif ~exist(songFile, 'file')
                                 beep;
                                 disp('Warning: Alarm sound file not found! Check the path and file type.');
                            end
                            
                        else 
                            % B. 심박수가 90 bpm 미만일 때: 즉시 정지
                            if ~isempty(app.AudioPlayerHandle) && isvalid(app.AudioPlayerHandle) && isplaying(app.AudioPlayerHandle)
                                stop(app.AudioPlayerHandle);
                                app.AlarmPlayStartTime = []; % 정지했으므로 시간 기록 초기화
                            end
                        end
                        
                        
                        % 2. 15초 시간 제한 확인 (심박수 조건과 관계없이 실행)
                        if ~isempty(app.AlarmPlayStartTime) && isvalid(app.AudioPlayerHandle) && isplaying(app.AudioPlayerHandle)
                            % 현재 시간과 시작 시간의 차이(일 단위)를 초 단위로 변환
                            elapsedTime = (now - app.AlarmPlayStartTime) * 24 * 3600; 
                            
                            if elapsedTime >= app.AlarmDuration
                                % 15초가 지났다면 정지
                                stop(app.AudioPlayerHandle);
                                app.AlarmPlayStartTime = []; % 시간 기록 초기화
                                disp('Alarm stopped after 15 seconds duration.');
                            end
                        end
                        % ==================================================
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        tmp3 = cast(tmp, 'uint8');
                        write(app.usb_nrf, tmp3, 'uint8');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    else
                        app.HeartRateEditField.Value = 'invalid';
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        write(app.usb_nrf, 0, 'uint8');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                end
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%% Data saving %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function SaveData(app)
            [file, path]=uiputfile('*.mat');
            f=fullfile(path, file);
            variable_list = app.Data.variable_list;

            str = 'save(f ';
            for i=1:length(variable_list)
                str1 = sprintf('%s = app.Data.%s;', variable_list{i}, variable_list{i});
                eval(str1);
                str = sprintf('%s, ''%s''', str, variable_list{i});
            end
            str = [str ');'];
            eval(str);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Data object
            app.SerialFlag = 0; % No serial data
            app.SerialStartButton.Enable = 'off';
            app.SerialStopButton.Enable = 'off';
            app.SerialSaveButton.Enable = 'off';
            app.LivePlotLine1 = plot(app.plot1, NaN, NaN);
            app.AudioPlayerHandle = [];
        end

        % Button pushed function: SerialConnectButton
        function SerialConnect(app, event)
            %%%%%%%%%%%%%% HN %%%%%%%%%%%%%%
            %status = SerialCommRequest(app, app.PortNumberDropDown.Value, str2double(app.BaudRateDropDown.Value));
            %if status == 1
            app.SerialConnectionLamp.Color = [0 1 0];
            app.SerialStartButton.Enable = 'on';

            app.Data.st_idx = 1; % initial plot start index
            app.Data.fs = 100;
            app.Data.end_idx = app.Data.fs/10; % initial plot end index

        end

        % Value changed function: PortNumberDropDown
        function PortNumberDropDownValueChanged(app, event)

        end

        % Value changed function: BaudRateDropDown
        function BaudRate(app, event)

        end

        % Button down function: plot1
        function dataPlot1(app, event)

        end

        % Button pushed function: SerialStopButton
        function SerialStop(app, event)
            app.SerialStartButton.Enable = 'on';
            app.SerialStopButton.Enable = 'off';
            app.SerialConnectButton.Enable = 'off';
            app.SerialSaveButton.Enable = 'on';
            app.PlotFlag = 0; % 1: plot, 0: pause
            app.SerialFlag = 0;

            SerialCommRequest(app, app.PortNumberDropDown.Value, str2double(app.BaudRateDropDown.Value), 0);
            %             delete(app.usb_nrf.serialPortInstance);

            %updateplot(app);
            % 💡 추가: 앱 정지 시 노래 재생 중지
            % ======================================
            if ~isempty(app.AudioPlayerHandle) && isvalid(app.AudioPlayerHandle)
                stop(app.AudioPlayerHandle);
            end


        end

        % Button pushed function: SerialSaveButton
        function SerialSave(app, event)
            SaveData(app)
        end

        % Button pushed function: SerialStartButton
        function SerialStart(app, event)
            app.SerialStartButton.Enable = 'off';
            app.SerialStopButton.Enable = 'on';
            app.SerialConnectButton.Enable = 'off';
            app.SerialSaveButton.Enable = 'off';
            app.SerialFlag = 1; % Using serial data
            app.PlotFlag = 1; % 1: plot, 0: pause

            %%%%%%%%%%%%%%%%% HN
            % ECG Filter
            [app.b_h_b, app.a_h_b] = butter(5, 0.5/(app.Data.fs/2), 'high');
            [app.b_l_b, app.a_l_b] = butter(5, 40/(app.Data.fs/2), 'low');

            % variable init.
            app.dX_h_1          =  zeros(1,6);
            app.dY_h_1          =  zeros(1,6);
            app.dX_l_30         =  zeros(1,6);
            app.dY_l_30         =  zeros(1,6);

            app.Window1         =  zeros(1, round(app.Data.fs*0.3));
            app.Window2         =  zeros(1, round(app.Data.fs*0.2));
            app.Window3         =  zeros(1, round(app.Data.fs*0.1));

            app.len1            =  round(app.Data.fs*0.3);
            app.len2            =  round(app.Data.fs*0.2);
            app.len3            =  round(app.Data.fs*0.1);

            app.PEAK_BUFF       =  (zeros(1,3));
            app.PEAK_LOC        =  (0);
            app.PEAK_INTV       =  (0);

            app.F_PEAK_LOC       =  ([]);
            app.F_PEAK_INTV      =  ([]);

            app.MEAN_HR          =  ([]);
            app.cnt             =  (0);
            app.pre_value = 0;
            % ▼▼▼ [추가할 코드] 측정 시작 시 기록 초기화 ▼▼▼
            app.HeartRateHistory = [];


            SerialCommRequest(app, app.PortNumberDropDown.Value, str2double(app.BaudRateDropDown.Value), 1);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HealthMonitorLabel = uilabel(app.UIFigure);
            app.HealthMonitorLabel.Text = 'Health Monitor';
            app.HealthStatusPanel = uipanel(app.UIFigure);
            app.HealthStatusPanel.Title = 'Health Status';
            app.HeartRateEditFieldLabel = uilabel(app.HealthStatusPanel);
            app.HeartRateEditFieldLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.HealthStatusPanel, 'text');
            app.HeartRateEditField.Tag = 'output_HR';
            app.SettingTabGroup = uitabgroup(app.UIFigure);
            app.SerialCommSettingTab = uitab(app.SettingTabGroup);
            app.SerialCommSettingTab.Title = 'Serial Comm Setting';
            app.PortNumberDropDownLabel = uilabel(app.SerialCommSettingTab);
            app.PortNumberDropDownLabel.Text = 'Port Number';
            app.PortNumberDropDown = uidropdown(app.SerialCommSettingTab);
            app.PortNumberDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15', 'COM16', 'COM17', 'COM18', 'COM19', 'COM20', 'COM21', 'COM22', 'COM23'};
            app.PortNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @PortNumberDropDownValueChanged, true);
            app.PortNumberDropDown.Tag = 'portNumber';
            app.PortNumberDropDown.Value = 'COM1';
            app.BaudRateDropDownLabel = uilabel(app.SerialCommSettingTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.SerialCommSettingTab);
            app.BaudRateDropDown.Items = {'9600', '115200'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRate, true);
            app.BaudRateDropDown.Tag = 'baudRate';
            app.BaudRateDropDown.Value = '115200';
            app.SerialConnectButton = uibutton(app.SerialCommSettingTab, 'push');
            app.SerialConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SerialConnect, true);
            app.SerialConnectButton.Tag = 'connect';
            app.SerialConnectButton.Text = 'Connect';
            app.SerialConnectionLamp = uilamp(app.SerialCommSettingTab);
            app.SerialConnectionLamp.Tag = 'connection_flag';
            app.SerialStopButton = uibutton(app.SerialCommSettingTab, 'push');
            app.SerialStopButton.ButtonPushedFcn = createCallbackFcn(app, @SerialStop, true);
            app.SerialStopButton.Tag = 'stop';
            app.SerialStopButton.Text = 'Stop';
            app.SerialSaveButton = uibutton(app.SerialCommSettingTab, 'push');
            app.SerialSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SerialSave, true);
            app.SerialSaveButton.Tag = 'save';
            app.SerialSaveButton.Text = 'Save';
            app.SerialStartButton = uibutton(app.SerialCommSettingTab, 'push');
            app.SerialStartButton.ButtonPushedFcn = createCallbackFcn(app, @SerialStart, true);
            app.SerialStartButton.Tag = 'stop';
            app.SerialStartButton.Text = 'Start';
            app.GraphTabGroup = uitabgroup(app.UIFigure);
            app.BioSignalsTab = uitab(app.GraphTabGroup);
            app.BioSignalsTab.Title = 'BioSignals';
            app.plot1 = uiaxes(app.BioSignalsTab);
            title(app.plot1, 'ECG')
            xlabel(app.plot1, 'Time (s)')
            zlabel(app.plot1, 'Z')
            app.plot1.ButtonDownFcn = createCallbackFcn(app, @dataPlot1, true);
            app.TimewindowsEditFieldLabel = uilabel(app.BioSignalsTab);
            app.TimewindowsEditFieldLabel.Text = 'Time window (s)';
            app.TimewindowEditField = uieditfield(app.BioSignalsTab, 'numeric');
            app.TimewindowEditField.Value = 10;
            app.HeartmaxLabel_2 = uilabel(app.UIFigure);
            app.HeartmaxLabel_2.Text = 'Heart max';
            app.MaxField = uieditfield(app.UIFigure, 'text');
            app.MaxField.Tag = 'output_HR';
            app.HeartmaxLabel = uilabel(app.UIFigure);
            app.HeartmaxLabel.Text = 'Heart min';
            app.MinField = uieditfield(app.UIFigure, 'text');
            app.MinField.Tag = 'output_HR';
            app.HeartaverLabel = uilabel(app.UIFigure);
            app.HeartaverLabel.Text = 'Heart aver';
            app.AvgField = uieditfield(app.UIFigure, 'text');
            app.AvgField.Tag = 'output_HR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HealthMonitor_v5Copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end